classdef fsController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ShaperfoButtonGroup     matlab.ui.container.ButtonGroup
        AutoButton              matlab.ui.control.RadioButton
        ManualButton            matlab.ui.control.RadioButton
        NoteLabel               matlab.ui.control.Label
        TargetFoSlider          matlab.ui.control.Slider
        Label_2                 matlab.ui.control.Label
        SlopeSlider             matlab.ui.control.Slider
        Label                   matlab.ui.control.Label
        TargetfoEditField       matlab.ui.control.NumericEditField
        TargetfoEditFieldLabel  matlab.ui.control.Label
        modGainAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        CommonInfo % shared property
    end
    
    methods (Access = private)
        
        function results = initializeGraphic(app)
            fftl = 2048;
            fs = 8000;
            fL = 55;
            app.CommonInfo.fftl = fftl;
            app.CommonInfo.fs = fftl;
            fx = (0:fftl-1)'/fftl*fs;
            app.CommonInfo.fx = fx;
            app.CommonInfo.slope = 1;
            targetFo = app.TargetfoEditField.Value;
            gainIndB = fx * 0; 
            fxBi = fx;
            fxBi(fx>fs/2) = fxBi(fx>fs/2) - fs;
            app.CommonInfo.fxBi = fxBi;
            gainIndB(abs(fxBi) > targetFo) = app.SlopeSlider.Value ...
                * log2(abs(fxBi(abs(fxBi) > targetFo))/targetFo);
            axes(app.modGainAxes);
            app.CommonInfo.gainHandle = semilogx(app.modGainAxes, fxBi(fxBi > 0), gainIndB(fxBi > 0));
            grid(app.modGainAxes, 'on');
            axis(app.modGainAxes, [fL fs/2 -80 80]);
            xlabel(app.modGainAxes, 'frequency (Hz)')
            ylabel(app.modGainAxes, 'gain (dB)')
            app.CommonInfo.gain = 10 .^ (gainIndB/10);
            app.CommonInfo.gainIndB = gainIndB;
            results = 'ok';
            app.CommonInfo.foUseMode = 'Manual';
            app.CommonInfo.noteName = {'C';'C#';'D';'D#';'E';'F';'F#';'G';'G#';'A';'A#';'B'};
        end
        
        function results = updateGainPlot(app)
            results = 'ok';
            changingValue = app.CommonInfo.slope;
            fxBi = app.CommonInfo.fxBi;
            gainIndB = fxBi * 0;
            targetFo = app.TargetfoEditField.Value;
            gainIndB(abs(fxBi) > targetFo) = changingValue ...
                * log2(abs(fxBi(abs(fxBi) > targetFo))/targetFo);
            set(app.CommonInfo.gainHandle, 'ydata', gainIndB(fxBi > 0));
            app.CommonInfo.gain = 10 .^ (gainIndB/10);
            app.CommonInfo.gainIndB = gainIndB;
            tmpId = round(12*log2(targetFo/55)) + 9;
            scaleId = floor(tmpId/12) + 1;
            noteNameId = rem(tmpId, 12) + 1;
            app.NoteLabel.Text = [app.CommonInfo.noteName{noteNameId} ...
                num2str(scaleId)];
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeGraphic(app);
        end

        % Value changing function: SlopeSlider
        function SlopeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CommonInfo.slope = changingValue;
            fxBi = app.CommonInfo.fxBi;
            gainIndB = fxBi * 0; 
            targetFo = app.TargetfoEditField.Value;
            gainIndB(abs(fxBi) > targetFo) = changingValue ...
                * log2(abs(fxBi(abs(fxBi) > targetFo))/targetFo);
            set(app.CommonInfo.gainHandle, 'ydata', gainIndB(fxBi > 0));
            app.CommonInfo.gain = 10 .^ (gainIndB/10);
            app.CommonInfo.gainIndB = gainIndB;
            drawnow
        end

        % Value changing function: TargetFoSlider
        function TargetFoSliderValueChanging(app, event)
            changingValue = event.Value;
            targetFo = 55 * 2^(round(changingValue)/12);
            app.TargetfoEditField.Value = round(targetFo*10)/10;
            updateGainPlot(app);
        end

        % Value changed function: TargetfoEditField
        function TargetfoEditFieldValueChanged(app, event)
            value = app.TargetfoEditField.Value;
            if value >0
                foSliderValue = round(12*log2(value/55));
                app.TargetfoEditField.Value = round(10*55*2^(foSliderValue/12))/10;
                updateGainPlot(app);
            end
        end

        % Selection changed function: ShaperfoButtonGroup
        function ShaperfoButtonGroupSelectionChanged(app, event)
            selectedButton = app.ShaperfoButtonGroup.SelectedObject;
            %disp(selectedButton.Text)
            app.CommonInfo.foUseMode = selectedButton.Text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.modGainAxes = uiaxes(app.UIFigure);
            title(app.modGainAxes, 'Title')
            xlabel(app.modGainAxes, 'X')
            ylabel(app.modGainAxes, 'Y')
            zlabel(app.modGainAxes, 'Z')
            app.TargetfoEditFieldLabel = uilabel(app.UIFigure);
            app.TargetfoEditFieldLabel.Text = 'Target fo';
            app.TargetfoEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetfoEditField.ValueChangedFcn = createCallbackFcn(app, @TargetfoEditFieldValueChanged, true);
            app.TargetfoEditField.Value = 110;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.SlopeSlider = uislider(app.UIFigure);
            app.SlopeSlider.Limits = [-12 12];
            app.SlopeSlider.ValueChangingFcn = createCallbackFcn(app, @SlopeSliderValueChanging, true);
            app.SlopeSlider.Value = 1;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {''; ''};
            app.TargetFoSlider = uislider(app.UIFigure);
            app.TargetFoSlider.Limits = [0 63];
            app.TargetFoSlider.MajorTicks = [0 12 24 36 48 60];
            app.TargetFoSlider.MajorTickLabels = {'A1', 'A2', 'A3', 'A4', 'A5', 'A6'};
            app.TargetFoSlider.ValueChangingFcn = createCallbackFcn(app, @TargetFoSliderValueChanging, true);
            app.TargetFoSlider.Value = 12;
            app.NoteLabel = uilabel(app.UIFigure);
            app.NoteLabel.Text = 'A2';
            app.ShaperfoButtonGroup = uibuttongroup(app.UIFigure);
            app.ShaperfoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShaperfoButtonGroupSelectionChanged, true);
            app.ShaperfoButtonGroup.Title = 'Shaper fo';
            app.ManualButton = uiradiobutton(app.ShaperfoButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutoButton = uiradiobutton(app.ShaperfoButtonGroup);
            app.AutoButton.Text = 'Auto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fsController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end